classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button_3   matlab.ui.control.Button
        ListBox_2  matlab.ui.control.ListBox
        Label_3    matlab.ui.control.Label
        Button_2   matlab.ui.control.Button
        ListBox    matlab.ui.control.ListBox
        Label_2    matlab.ui.control.Label
        Button     matlab.ui.control.Button
        path0      matlab.ui.control.EditField
        Label      matlab.ui.control.Label
        A3         matlab.ui.control.UIAxes
        B3         matlab.ui.control.UIAxes
        B2         matlab.ui.control.UIAxes
        A2         matlab.ui.control.UIAxes
        B1         matlab.ui.control.UIAxes
        A1         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            clear sound
            p0=app.path0.Value;
            [x, fs0] = audioread(p0);
            sound(x,fs0);
            t = (0:length(x)-1) / fs0;  % 时间向量
            plot(app.A1,t,x);
            if size(x, 2) > 1
            signal = x(:, 1);  % 取第一个通道
            end
            N = length(signal);  % 信号长度
            signal_fft = fft(signal);  % 傅里叶变换
            f = (-N/2:N/2-1)*(fs0/N);  % 使用负频率范围
            magnitude = abs(fftshift(signal_fft)) / N;  % 归一化幅度并居中
            plot(app.B1,f, magnitude);  % 只绘制频率范围为正的部分
        end

        % Button down function: A1
        function A1ButtonDown(app, event)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           clear sound
            p0=app.path0.Value;
            pir=app.ListBox.Value;
            pir2=app.ListBox_2.Value;
            [x, fs0] = audioread(p0);
            if pir
            [h1,fs]=audioread(pir);
            else
                h1=1;
                fs=fs0;
            end
            if pir2
            [h2,fs2]=audioread(pir2);
            else
                h2=1;
                fs2=fs0;
            end
            h=conv(h1(:,1),h2(:,1));
             t0 = (0:length(h)-1) / fs0;% 时间向量
             plot(app.A3,t0,h);
              N0 = length(h);  % 信号长度
            h_fft = fft(h);  % 傅里叶变换
            f = (-N0/2:N0/2-1)*(fs0/N0);  % 使用负频率范围
            magnitude0 = abs(fftshift(h_fft)) / N0;  % 归一化幅度并居中
            plot(app.B3,f, magnitude0); 

            h=h(1:round(fs/fs0):end,:);
            if size(x, 2) > 1
            signal = x(:, 1);  % 取第一个通道
            end
            y=conv(signal,h(:,1));
            sound(y,fs0);
            t = (0:length(y)-1) / fs0;  % 时间向量
            plot(app.A2,t,y);
           
            N = length(y);  % 信号长度
            y_fft = fft(y);  % 傅里叶变换
            f = (-N/2:N/2-1)*(fs0/N);  % 使用负频率范围
            magnitude = abs(fftshift(y_fft)) / N;  % 归一化幅度并居中
            plot(app.B2,f, magnitude); 

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            clear sound
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.A1 = uiaxes(app.UIFigure);
            title(app.A1, '原声时域信号')
            xlabel(app.A1, 't /s')
            ylabel(app.A1, ' ')
            zlabel(app.A1, 'Z')
            app.A1.ButtonDownFcn = createCallbackFcn(app, @A1ButtonDown, true);
            app.B1 = uiaxes(app.UIFigure);
            title(app.B1, '原声频域信号')
            xlabel(app.B1, 'w /HZ')
            zlabel(app.B1, 'Z')
            app.A2 = uiaxes(app.UIFigure);
            title(app.A2, '混响时域信号')
            xlabel(app.A2, 't /s')
            ylabel(app.A2, ' ')
            zlabel(app.A2, 'Z')
            app.B2 = uiaxes(app.UIFigure);
            title(app.B2, '混响频域信号')
            xlabel(app.B2, 'w /HZ')
            zlabel(app.B2, 'Z')
            app.B3 = uiaxes(app.UIFigure);
            title(app.B3, 'IR频域信号')
            xlabel(app.B3, 'w /HZ')
            zlabel(app.B3, 'Z')
            app.A3 = uiaxes(app.UIFigure);
            title(app.A3, 'IR时域信号')
            xlabel(app.A3, 't /s')
            ylabel(app.A3, ' ')
            zlabel(app.A3, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '音频路径';
            app.path0 = uieditfield(app.UIFigure, 'text');
            app.path0.Value = 'D:\HuaweiMoveData\Users\86135\Desktop\信号大作业\12月5日 上午9点06分.m4a';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '原声';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '混响类型';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'原声', '寝室', '楼道', 'C117'};
            app.ListBox.ItemsData = {'', 'IR寝室2.wav', 'stair.wav', 'C117.mp3'};
            app.ListBox.Value = 'IR寝室2.wav';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '混响';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '质感';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'无', '金属', '玻璃', '木头'};
            app.ListBox_2.ItemsData = {'', '金属0.m4a', '玻璃.m4a', '木头.m4a'};
            app.ListBox_2.Value = '金属0.m4a';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '停止';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end